classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GiuntiPanel               matlab.ui.container.Panel
        EliminaButton             matlab.ui.control.Button
        TipologiaDropDownLabel    matlab.ui.control.Label
        TipologiaDropDown         matlab.ui.control.DropDown
        AggiungiButton            matlab.ui.control.Button
        RiferimentoDropDownLabel  matlab.ui.control.Label
        RiferimentoDropDown       matlab.ui.control.DropDown
        GiuntiTable               matlab.ui.control.Table
        TabGroupAlgoritmi         matlab.ui.container.TabGroup
        PolinomiocubicoTab        matlab.ui.container.Tab
        VelocitaTable             matlab.ui.control.Table
        SimulaPolinomioButton     matlab.ui.control.Button
        SalvaAnimazioneButton     matlab.ui.control.Button
        PolinomioButtonGroup      matlab.ui.container.ButtonGroup
        velocitassegnateButton    matlab.ui.control.RadioButton
        velocitcalcolateButton    matlab.ui.control.RadioButton
        AnimazioneButton          matlab.ui.control.Button
        ProfilitrapezoidaliTab    matlab.ui.container.Tab
        TrapezioTable             matlab.ui.control.Table
        TrapezioButtonGroup       matlab.ui.container.ButtonGroup
        velocitcButton            matlab.ui.control.RadioButton
        accelerazionicButton      matlab.ui.control.RadioButton
        SimulaTrapezioButton      matlab.ui.control.Button
        AnticipiTable             matlab.ui.control.Table
        PercorsoPanel             matlab.ui.container.Panel
        AggiungiColButton         matlab.ui.control.Button
        EliminaColButton          matlab.ui.control.Button
        PosizioniTable            matlab.ui.control.Table
        IntervalliTempoTable      matlab.ui.control.Table
        TabGroupGrafici           matlab.ui.container.TabGroup
        BenvenutoTab              matlab.ui.container.Tab
        HTML                      matlab.ui.control.HTML
    end

    
    properties (Access = public)
        selectedIndex = -1
        riferimenti = {}
        tipologie = {}
    end
    
    methods (Access = private)
        
        function result = trasformaIntervalli(app, intervalli)
            result = zeros(1, size(intervalli, 2));
            for i = 2 : size(intervalli, 2)
                result(i) = result(i-1) + str2num(intervalli{i});
            end
        end
        
        function [] = stampaGraficiGUI(app, parametri, tempi)
            if length(app.TabGroupGrafici.Children) > 1
                app.TabGroupGrafici.Children(2:end).delete
            end
            for j = 1 : size(parametri, 1)
                row = app.GiuntiTable.Data(j, :);
                tab = uitab(app.TabGroupGrafici, 'Title', strcat(row{1}, ' - ', row{2}));
                stampaGraficoGUI(tab, parametri, tempi, j);
            end
            app.TabGroupGrafici.SelectedTab = app.TabGroupGrafici.Children(2);
        end
        
        function [] = stampaGraficiTrapeziGUI(app, q2c, tc, percorso, tempi, anticipi)
            if length(app.TabGroupGrafici.Children) > 1
                app.TabGroupGrafici.Children(2:end).delete
            end
            for j = 1 : size(percorso, 1)
                row = app.GiuntiTable.Data(j, :);
                tab = uitab(app.TabGroupGrafici, 'Title', strcat(row{1}, ' - ', row{2}), 'Scrollable', 'on');
                panel = uipanel(tab, 'Scrollable', 'off', 'Position', [24, 24, 400, 800], 'BorderType', 'none');
                stampaGraficoTrapeziGUI(panel, q2c, tc, percorso, tempi, anticipi, j);
            end
            app.TabGroupGrafici.SelectedTab = app.TabGroupGrafici.Children(2);
        end
        
        function [] = checkSimulazione(app)
            if ~ismember({''}, app.PosizioniTable.Data) && ~ismember({''}, app.IntervalliTempoTable.Data)
                polinomioButton = app.PolinomioButtonGroup.SelectedObject.Text;
                if strcmp(polinomioButton, 'velocità calcolate')
                    app.SimulaPolinomioButton.Enable = 'on';
                    app.AnimazioneButton.Enable = 'on';
                    app.SalvaAnimazioneButton.Enable = 'on';
                elseif strcmp(polinomioButton, 'velocità assegnate')
                    if ~ismember({''}, app.VelocitaTable.Data)
                        app.SimulaPolinomioButton.Enable = 'on';
                        app.AnimazioneButton.Enable = 'on';
                        app.SalvaAnimazioneButton.Enable = 'on';
                    else
                        app.SimulaPolinomioButton.Enable = 'off';
                        app.AnimazioneButton.Enable = 'off';
                        app.SalvaAnimazioneButton.Enable = 'off';
                    end
                end
                if ~ismember({''}, app.TrapezioTable.Data)
                    if ~isa(app.AnticipiTable.Data, 'double')
                        if ~ismember({''}, app.AnticipiTable.Data)
                            app.SimulaTrapezioButton.Enable = 'on';
                        else
                            app.SimulaTrapezioButton.Enable = 'off';
                        end
                    else
                        app.SimulaTrapezioButton.Enable = 'off';
                    end
                else
                    app.SimulaTrapezioButton.Enable = 'off';
                end
            end
        end
        
        function [parametri, tempi] = calcolaParametriPolinomio(app)
            tempi = app.trasformaIntervalli([0, app.IntervalliTempoTable.Data]);
            percorso = cellfun(@str2num, app.PosizioniTable.Data(:, :));
            selectedButton = app.PolinomioButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton, 'velocità assegnate')
                velocita = cellfun(@str2num, app.VelocitaTable.Data(:, :));
                parametri = generaPolinomiCubici(percorso, velocita, tempi);
            elseif strcmp(selectedButton, 'velocità calcolate')
                velocita = calcolaVelocita(percorso, tempi);
                parametri = generaPolinomiCubici(percorso, velocita, tempi);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AggiungiButton
        function AggiungiButtonPushed(app, event)
            tipo = app.TipologiaDropDown.Value;
            app.tipologie{end + 1} = tipo;
            ref = app.RiferimentoDropDown.Value;
            app.riferimenti{end + 1} = ref;
            giuntiRow = {tipo, ref};
            app.GiuntiTable.Data = [app.GiuntiTable.Data; giuntiRow];
            
            [num_rows, num_cols] = size(app.PosizioniTable.Data);
            if num_rows == 0 && num_cols == 0
                app.PosizioniTable.Data = {''};
                app.VelocitaTable.Data = {''};
                return;
            end
            row = repmat({''}, 1, num_cols);
            app.PosizioniTable.Data = [app.PosizioniTable.Data; row];
            app.VelocitaTable.Data = [app.VelocitaTable.Data; row];
            app.TrapezioTable.Data = [app.TrapezioTable.Data; row(1 : end - 1)];
        end

        % Button pushed function: EliminaButton
        function EliminaButtonPushed(app, event)
            if app.selectedIndex == -1
                msgbox('Seleziona un giunto dalla lista prima di eliminare.', 'Errore', 'error', 'modal');
                return;
            end
            app.riferimenti(app.selectedIndex) = [];
            app.tipologie(app.selectedIndex) = [];
            app.GiuntiTable.Data(app.selectedIndex, :) = [];
            app.PosizioniTable.Data(app.selectedIndex, :) = [];
            app.VelocitaTable.Data(app.selectedIndex, :) = [];
            if ~isempty(app.TrapezioTable.Data)
                app.TrapezioTable.Data(app.selectedIndex, :) = [];
            end
            app.selectedIndex = -1;
        end

        % Button pushed function: AggiungiColButton
        function AggiungiColButtonPushed(app, event)
            [num_rows, num_cols] = size(app.PosizioniTable.Data);
            if num_rows == 0 && num_cols == 0
                msgbox('Aggiungi un giunto prima di procedere.', 'Errore', 'error', 'modal');
                return;
            end
            if num_cols == 1
                app.TrapezioTable.Data = repmat({''}, num_rows, 1);
                app.IntervalliTempoTable.Data = {''};
            end
            if num_cols > 1
                formatoInterv = 't%d -> t%d';
                formatoVelc = 'q''c(t%d) -> q''c(t%d)';
                colonnaInterv = sprintf(formatoInterv, num_cols, num_cols + 1);
                colonnaVelc = sprintf(formatoVelc, num_cols, num_cols + 1);
                app.IntervalliTempoTable.ColumnName{end + 1} = colonnaInterv;
                app.TrapezioTable.ColumnName{end + 1} = colonnaVelc;
                app.IntervalliTempoTable.Data = [app.IntervalliTempoTable.Data, {''}];
                app.TrapezioTable.Data = [app.TrapezioTable.Data, repmat({''}, num_rows, 1)];
                if num_cols == 2
                    app.AnticipiTable.Data = {''};
                else
                    formatoAnticipo = 'anticipo t%d -> t%d';
                    colonnaAnticipo = sprintf(formatoAnticipo, num_cols, num_cols + 1);
                    app.AnticipiTable.ColumnName{end + 1} = colonnaAnticipo;
                    app.AnticipiTable.Data = [app.AnticipiTable.Data, {''}];
                end
            end
            formatoPos = 'q(t%d)';
            formatoVel = 'q''(t%d)';
            colonnaPos = sprintf(formatoPos, num_cols + 1);
            colonnaVel = sprintf(formatoVel, num_cols + 1);
            app.PosizioniTable.ColumnName{end + 1} = colonnaPos;
            app.VelocitaTable.ColumnName{end + 1} = colonnaVel;
            column = repmat({''}, num_rows, 1);
            app.PosizioniTable.Data = [app.PosizioniTable.Data, column];
            app.VelocitaTable.Data = [app.VelocitaTable.Data, column];
        end

        % Button pushed function: EliminaColButton
        function EliminaColButtonPushed(app, event)
            [~, num_cols] = size(app.PosizioniTable.Data);
            if num_cols <= 1
                msgbox('Impossibile eliminare.', 'Errore', 'error', 'modal');
                return;
            end
            app.PosizioniTable.Data = app.PosizioniTable.Data(:, 1 : end - 1);
            app.VelocitaTable.Data = app.VelocitaTable.Data(:, 1 : end - 1);
            app.PosizioniTable.ColumnName = app.PosizioniTable.ColumnName(1 : end - 1);
            app.VelocitaTable.ColumnName = app.VelocitaTable.ColumnName(1 : end - 1);
            if size(app.IntervalliTempoTable.Data, 2) > 1
                app.IntervalliTempoTable.Data = app.IntervalliTempoTable.Data(:, 1 : end - 1);
                app.TrapezioTable.Data = app.TrapezioTable.Data(:, 1: end - 1);
                app.IntervalliTempoTable.ColumnName = app.IntervalliTempoTable.ColumnName(1 : end - 1);
                app.TrapezioTable.ColumnName = app.TrapezioTable.ColumnName(1 : end - 1);
            else
                app.IntervalliTempoTable.Data = [];
                app.TrapezioTable.Data = [];
                app.IntervalliTempoTable.ColumnName = app.IntervalliTempoTable.ColumnName(1);
                app.TrapezioTable.ColumnName = app.TrapezioTable.ColumnName(1);
            end
            if size(app.AnticipiTable.Data, 2) > 1
                app.AnticipiTable.Data = app.AnticipiTable.Data(:, 1 : end - 1);
                app.AnticipiTable.ColumnName = app.AnticipiTable.ColumnName(1 : end - 1);
            else
                app.AnticipiTable.Data = [];
                app.AnticipiTable.ColumnName = app.AnticipiTable.ColumnName(1);
            end
        end

        % Button pushed function: SimulaPolinomioButton
        function SimulaPolinomioButtonPushed(app, event)
            [parametri, tempi] = app.calcolaParametriPolinomio();
            app.stampaGraficiGUI(parametri, tempi);
            close all;
        end

        % Cell selection callback: GiuntiTable
        function GiuntiTableCellSelection(app, event)
            event.Indices(end, 1)
            app.selectedIndex = event.Indices(end, 1);
        end

        % Selection changed function: PolinomioButtonGroup
        function PolinomioButtonGroupSelectionChanged(app, event)
            selectedButton = app.PolinomioButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton, 'velocità assegnate')
               app.VelocitaTable.Enable = 'on';
            else
                app.VelocitaTable.Enable = 'off';
            end
            app.checkSimulazione()
        end

        % Button pushed function: SimulaTrapezioButton
        function SimulaTrapezioButtonPushed(app, event)
            tempi = app.trasformaIntervalli([0, app.IntervalliTempoTable.Data]);
            anticipi = app.trasformaIntervalli([0, app.AnticipiTable.Data]);
            percorso = cellfun(@str2num, app.PosizioniTable.Data(:, :));
            valoriC = cellfun(@str2num, app.TrapezioTable.Data(:, :));
            selectedButton = app.TrapezioButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton, 'velocità c')
                [q1c, q2c, tc] = generaTrapezi(percorso, tempi, valoriC, true);
                convalida = convalidaParametriTrapezio(percorso, tempi, q1c, q2c, true);
            else
                [q1c, q2c, tc] = generaTrapezi(percorso, tempi, valoriC, false);
                convalida = convalidaParametriTrapezio(percorso, tempi, q1c, q2c, false);
            end
            if ~convalida
                msgbox('I parametri non sono validi inserisci dei valori adeguati.', 'Errore', 'error', 'modal');
                return;
            end
            app.stampaGraficiTrapeziGUI(q2c, tc, percorso, tempi, anticipi);
            close all;
        end

        % Selection changed function: TrapezioButtonGroup
        function TrapezioButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrapezioButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton, 'velocità c')
                formato = 'q''c(t%d) -> q''c(t%d)';
            else
                formato = 'q''''c(t%d) -> q''''c(t%d)';
            end
            for i = 1 : length(app.TrapezioTable.ColumnName)
                colonna = sprintf(formato, i, i + 1);
                app.TrapezioTable.ColumnName{i} = colonna;
            end
        end

        % Button pushed function: AnimazioneButton
        function AnimazioneButtonPushed(app, event)
            [parametri, tempi] = app.calcolaParametriPolinomio();
            animazioneGUI(parametri, tempi, app.tipologie, app.riferimenti, false);
        end

        % Cell edit callback: PosizioniTable
        function PosizioniTableCellEdit(app, event)
            app.checkSimulazione();
        end

        % Cell edit callback: IntervalliTempoTable
        function IntervalliTempoTableCellEdit(app, event)
            app.checkSimulazione();
        end

        % Cell edit callback: VelocitaTable
        function VelocitaTableCellEdit(app, event)
            app.checkSimulazione();
        end

        % Cell edit callback: TrapezioTable
        function TrapezioTableCellEdit(app, event)
            app.checkSimulazione();
        end

        % Cell edit callback: AnticipiTable
        function AnticipiTableCellEdit(app, event)
            app.checkSimulazione();
        end

        % Button pushed function: SalvaAnimazioneButton
        function SalvaAnimazioneButtonPushed(app, event)
            [parametri, tempi] = app.calcolaParametriPolinomio();
            animazioneGUI(parametri, tempi, app.tipologie, app.riferimenti, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GiuntiPanel = uipanel(app.UIFigure);
            app.GiuntiPanel.Title = 'Giunti';
            app.EliminaButton = uibutton(app.GiuntiPanel, 'push');
            app.EliminaButton.ButtonPushedFcn = createCallbackFcn(app, @EliminaButtonPushed, true);
            app.EliminaButton.Tooltip = {'Elimina il giunto selezionato'};
            app.EliminaButton.Text = 'Elimina';
            app.TipologiaDropDownLabel = uilabel(app.GiuntiPanel);
            app.TipologiaDropDownLabel.Tooltip = {'Definisce la tipologia del giunto'};
            app.TipologiaDropDownLabel.Text = 'Tipologia:';
            app.TipologiaDropDown = uidropdown(app.GiuntiPanel);
            app.TipologiaDropDown.Items = {'Rotoidale', 'Prismatico'};
            app.TipologiaDropDown.Tooltip = {'Definisce la tipologia del giunto'};
            app.TipologiaDropDown.Value = 'Rotoidale';
            app.AggiungiButton = uibutton(app.GiuntiPanel, 'push');
            app.AggiungiButton.ButtonPushedFcn = createCallbackFcn(app, @AggiungiButtonPushed, true);
            app.AggiungiButton.Tooltip = {'Aggiunge il giunto in base ai dati inseriti'};
            app.AggiungiButton.Text = 'Aggiungi';
            app.RiferimentoDropDownLabel = uilabel(app.GiuntiPanel);
            app.RiferimentoDropDownLabel.Tooltip = {'Asse di riferimento su quale avviene il movimento'};
            app.RiferimentoDropDownLabel.Text = 'Riferimento:';
            app.RiferimentoDropDown = uidropdown(app.GiuntiPanel);
            app.RiferimentoDropDown.Items = {'x', 'y', 'z'};
            app.RiferimentoDropDown.Tooltip = {'Asse di riferimento su quale avviene il movimento'};
            app.RiferimentoDropDown.Value = 'x';
            app.GiuntiTable = uitable(app.GiuntiPanel);
            app.GiuntiTable.ColumnName = {'Tipologia'; 'Riferimento'};
            app.GiuntiTable.CellSelectionCallback = createCallbackFcn(app, @GiuntiTableCellSelection, true);
            app.TabGroupAlgoritmi = uitabgroup(app.UIFigure);
            app.PolinomiocubicoTab = uitab(app.TabGroupAlgoritmi);
            app.PolinomiocubicoTab.Title = 'Polinomio cubico';
            app.VelocitaTable = uitable(app.PolinomiocubicoTab);
            app.VelocitaTable.ColumnName = {'q''(t1)'};
            app.VelocitaTable.ColumnEditable = true;
            app.VelocitaTable.CellEditCallback = createCallbackFcn(app, @VelocitaTableCellEdit, true);
            app.SimulaPolinomioButton = uibutton(app.PolinomiocubicoTab, 'push');
            app.SimulaPolinomioButton.ButtonPushedFcn = createCallbackFcn(app, @SimulaPolinomioButtonPushed, true);
            app.SimulaPolinomioButton.Enable = 'off';
            app.SimulaPolinomioButton.Tooltip = {'Simula la traiettoria in base ia dati inseriti'};
            app.SimulaPolinomioButton.Text = 'Simula';
            app.SalvaAnimazioneButton = uibutton(app.PolinomiocubicoTab, 'push');
            app.SalvaAnimazioneButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaAnimazioneButtonPushed, true);
            app.SalvaAnimazioneButton.Enable = 'off';
            app.SalvaAnimazioneButton.Tooltip = {'salva la simulazione in formato mp4'};
            app.SalvaAnimazioneButton.Text = 'Salva anim.';
            app.PolinomioButtonGroup = uibuttongroup(app.PolinomiocubicoTab);
            app.PolinomioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PolinomioButtonGroupSelectionChanged, true);
            app.PolinomioButtonGroup.Tooltip = {'Metodo con cui viene calcolata la traiettoria'};
            app.velocitassegnateButton = uiradiobutton(app.PolinomioButtonGroup);
            app.velocitassegnateButton.Text = 'velocità assegnate';
            app.velocitassegnateButton.Value = true;
            app.velocitcalcolateButton = uiradiobutton(app.PolinomioButtonGroup);
            app.velocitcalcolateButton.Text = 'velocità calcolate';
            app.AnimazioneButton = uibutton(app.PolinomiocubicoTab, 'push');
            app.AnimazioneButton.ButtonPushedFcn = createCallbackFcn(app, @AnimazioneButtonPushed, true);
            app.AnimazioneButton.Enable = 'off';
            app.AnimazioneButton.Text = 'Animazione';
            app.ProfilitrapezoidaliTab = uitab(app.TabGroupAlgoritmi);
            app.ProfilitrapezoidaliTab.Title = 'Profili trapezoidali';
            app.TrapezioTable = uitable(app.ProfilitrapezoidaliTab);
            app.TrapezioTable.ColumnName = {'q''c(t1) -> q''c(t2)'};
            app.TrapezioTable.ColumnEditable = true;
            app.TrapezioTable.CellEditCallback = createCallbackFcn(app, @TrapezioTableCellEdit, true);
            app.TrapezioButtonGroup = uibuttongroup(app.ProfilitrapezoidaliTab);
            app.TrapezioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrapezioButtonGroupSelectionChanged, true);
            app.TrapezioButtonGroup.Tooltip = {'Metodo con cui viene calcolata la traiettoria'};
            app.velocitcButton = uiradiobutton(app.TrapezioButtonGroup);
            app.velocitcButton.Text = 'velocità c';
            app.velocitcButton.Value = true;
            app.accelerazionicButton = uiradiobutton(app.TrapezioButtonGroup);
            app.accelerazionicButton.Text = 'accelerazioni c';
            app.SimulaTrapezioButton = uibutton(app.ProfilitrapezoidaliTab, 'push');
            app.SimulaTrapezioButton.ButtonPushedFcn = createCallbackFcn(app, @SimulaTrapezioButtonPushed, true);
            app.SimulaTrapezioButton.Enable = 'off';
            app.SimulaTrapezioButton.Tooltip = {'Simula la traiettoria in base ia dati inseriti'};
            app.SimulaTrapezioButton.Text = 'Simula';
            app.AnticipiTable = uitable(app.ProfilitrapezoidaliTab);
            app.AnticipiTable.ColumnName = {'anticipo t2 -> t3'};
            app.AnticipiTable.RowName = {'secondi'};
            app.AnticipiTable.ColumnEditable = true;
            app.AnticipiTable.CellEditCallback = createCallbackFcn(app, @AnticipiTableCellEdit, true);
            app.PercorsoPanel = uipanel(app.UIFigure);
            app.PercorsoPanel.Title = 'Percorso';
            app.AggiungiColButton = uibutton(app.PercorsoPanel, 'push');
            app.AggiungiColButton.ButtonPushedFcn = createCallbackFcn(app, @AggiungiColButtonPushed, true);
            app.AggiungiColButton.Tooltip = {'Aggiunge intervallo di tempo'};
            app.AggiungiColButton.Text = 'Aggiungi interv.';
            app.EliminaColButton = uibutton(app.PercorsoPanel, 'push');
            app.EliminaColButton.ButtonPushedFcn = createCallbackFcn(app, @EliminaColButtonPushed, true);
            app.EliminaColButton.Tooltip = {'Elimina l''ultimo intervallo di tempo'};
            app.EliminaColButton.Text = 'Elimina interv.';
            app.PosizioniTable = uitable(app.PercorsoPanel);
            app.PosizioniTable.ColumnName = {'q(t1)'};
            app.PosizioniTable.ColumnEditable = true;
            app.PosizioniTable.CellEditCallback = createCallbackFcn(app, @PosizioniTableCellEdit, true);
            app.IntervalliTempoTable = uitable(app.PercorsoPanel);
            app.IntervalliTempoTable.ColumnName = {'t1 -> t2'};
            app.IntervalliTempoTable.RowName = {'secondi'};
            app.IntervalliTempoTable.ColumnEditable = true;
            app.IntervalliTempoTable.CellEditCallback = createCallbackFcn(app, @IntervalliTempoTableCellEdit, true);
            app.TabGroupGrafici = uitabgroup(app.UIFigure);
            app.BenvenutoTab = uitab(app.TabGroupGrafici);
            app.BenvenutoTab.Title = 'Benvenuto!';
            app.HTML = uihtml(app.BenvenutoTab);
            app.HTML.HTMLSource = './benvenuto.html';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end